classdef myclassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RunButton                    matlab.ui.control.StateButton
        ThresholdingPanel            matlab.ui.container.Panel
        UIAxes_4                     matlab.ui.control.UIAxes
        Slider                       matlab.ui.control.Slider
        ValueEditFieldLabel          matlab.ui.control.Label
        ValueEditField               matlab.ui.control.NumericEditField
        ExitButton                   matlab.ui.control.StateButton
        Panel                        matlab.ui.container.Panel
        SegmentationPropertiesLabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        ExtractedFeaturesLabel       matlab.ui.control.Label
        UITable_2                    matlab.ui.control.Table
        FiguresPanel                 matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        ParameterSelectionPanel      matlab.ui.container.Panel
        Switch                       matlab.ui.control.ToggleSwitch
        Lamp                         matlab.ui.control.Lamp
        LoadButton                   matlab.ui.control.Button
        SelectImageLabel             matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        SegmentationButtonGroup      matlab.ui.container.ButtonGroup
        SupervisedButton             matlab.ui.control.ToggleButton
        UnsupervisedButton           matlab.ui.control.ToggleButton
        FeaturesButtonGroup          matlab.ui.container.ButtonGroup
        ColorButton                  matlab.ui.control.RadioButton
        MorphologyButton             matlab.ui.control.RadioButton
        TextureButton                matlab.ui.control.RadioButton
        AllButton                    matlab.ui.control.RadioButton
        ClassifierButtonGroup        matlab.ui.container.ButtonGroup
        LDAButton                    matlab.ui.control.RadioButton
        SVMButton                    matlab.ui.control.RadioButton
        KNNButton                    matlab.ui.control.RadioButton
        InputOuputPanel              matlab.ui.container.Panel
        TextArea                     matlab.ui.control.TextArea
        InputParametersLabel         matlab.ui.control.Label
        TextArea_2                   matlab.ui.control.TextArea
        OutputParametersLabel        matlab.ui.control.Label
    end


    properties (Access = public)
        allfiles  % Description
        thresholdValue
        selectedButton % Description

    end

    properties (Access = private)
        button % Description
        filename % Description
        pathname % Description
%         s % Description
%         selectedButton % Description

    end


    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            promptMessage = sprintf('Which dataset do you want to use?');
            app.button = questdlg(promptMessage, 'Dataset', 'Dataset 1', 'Dataset 2',...
                                'Dataset 3', 'Cancel');
            if strcmp(app.button, 'Cancel')
                return;
            end
            
            
            [~, app.pathname]  = uigetfile('*.*', 'Specify an image file'); 
            app.allfiles = dir( fullfile(app.pathname,'*.png') );%# list all *.xyz files
            app.allfiles = {app.allfiles.name}'; %'# file names   
            
            
            img_combined = [];
            Table = [];
            switch app.button
            % Separate dataset 1 into training and testing set
                   case 'Dataset 1'        
                   % reshape cell into 100 by 3 images
                   newshape = reshape(app.allfiles,[100,3]);
                   % Assign 15% of the data as testing set
                   testset = newshape(1:15, :);
                   % Assign 85% of data as testing set    
                   trainset = newshape(16:end,:);
        
            % Separate dataset 2 into training and testing set
                   case 'Dataset 2'
                   % reshape cell into 100 by 16 images
                   newshape = reshape(app.allfiles,[16,100]);
                   % Assign 15% of the data as testing set
                   testset = newshape(:,1:15);
                   % Assign 85% of data as testing set    
                   trainset = newshape(:,16:end);
            % Separate dataset 3 into training and testing set
                   case 'Dataset 3'
                   % reshape cell into 59 by 16 images
                   newshape = reshape(app.allfiles,[16,59]);
                   % Assign 15% of the data as testing set
                   testset = newshape(:,1:9);
                   % Assign 85% of data as testing set    
                   trainset = newshape(:,10:end);
             end 
            % 
            %                     image1 = imread(app.allfiles);
            %                     imshow(image1, 'Parent', app.UIAxes);
            %                     app.TextArea.Value = string(trainset);
            
            app.RunButton.Enable = 'on';
            app.ListBox.Enable = 'on';
            %             app.Slider.Enable = 'on';
            app.ListBox.Items =  app.allfiles;
            app.UnsupervisedButton.Enable = 'on';
            app.SupervisedButton.Enable = 'on';
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
          
            if  isequal(value,'Train')
                app.Lamp.Color = 'yellow';
                app.ListBox.Enable = 'off';
                app.Lamp.Enable = 'on';
                app.Slider.Enable = 'off';
            elseif isequal(value,'Test')
                app.Lamp.Enable = 'off';
                app.ListBox.Enable = 'on';
                app.Lamp.Color = 'green';
                app.Slider.Enable = 'on';
            %                 app.Slider.Value = 0.5;
                app.thresholdValue = 0.5;
            end
            %             app.LoadButton.Enable = 'on';
           
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            global image1 image2 image3 labeledImage coloredLabels T
            
            app.ListBox.Enable = 'on';
            app.ListBox.Items =  app.allfiles;
            %% Testing phase
            % Define value as what user selects from the listbox
            value = app.ListBox.Value;
            
            % Display raw image in top left figure
            image1 = imread(strcat(app.pathname,value));            
            imshow(image1, 'Parent', app.UIAxes);
            
            % Normalize the image selected by user
            image2 = normal3(image1, app.button);
            image3 = enhance(image2);
            app.selectedButton = app.SegmentationButtonGroup.SelectedObject.Text;
            
             if     isequal(app.selectedButton,'Unsupervised')
                    app.Slider.Enable = 'off';
            %                     [BW,~] = createMask(image3.img_histeq);
                    [BW,~,coloredLabels] = createMask(image3.img_histeq);
                    imshow(BW, 'Parent', app.UIAxes_2);
                    imshow(coloredLabels, 'Parent', app.UIAxes_3);
                    histogram(image3.img_histeq,'Parent', app.UIAxes_4);
            elseif isequal(app.selectedButton,'Supervised')
                    app.Slider.Enable = 'on';
                    app.thresholdValue  = app.Slider.Value;          
                    [labeledImage, coloredLabels, T] = nucleidetector(image3.img_histeq, app.thresholdValue);                   
                    imshow(labeledImage, 'Parent', app.UIAxes_2);
                    imshow(coloredLabels, 'Parent', app.UIAxes_3);% 
                    originalImage = rgb2gray(image3.img_histeq);
                    histogram(originalImage,'Parent', app.UIAxes_4);
                    app.UITable.Data = T;
            end
             
            % Classify image based on model
            classifier = app.ClassifierButtonGroup.SelectedObject;
            if isequal(classifier,'FISHER')
                
            % Knn model
            elseif  isequal(classifier,'KNN')
                
            % SVM Model
            elseif isequal(classifier,'SVM')
                
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.ListBox.Value;
            app.TextArea.Value = value;
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.TextArea.Value = value;
                         app.RunButton.Enable = 'on';            
        end

        % Value changed function: ExitButton
        function ExitButtonValueChanged(app, event)
            %             value = app.ExitButton.Value;
             close all force
        end

        % Selection changed function: SegmentationButtonGroup
        function SegmentationButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.SegmentationButtonGroup.SelectedObject.Text;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            global image3 labeledImage coloredLabels T
            app.thresholdValue  = app.Slider.Value;   
            [labeledImage, coloredLabels, T] = nucleidetector(image3.img_histeq, app.thresholdValue);                   
            imshow(labeledImage, 'Parent', app.UIAxes_2);
            imshow(coloredLabels, 'Parent', app.UIAxes_3);% 
            originalImage = rgb2gray(image3.img_histeq);
            histogram(originalImage,'Parent', app.UIAxes_4);
            app.UITable.Data = T;
            app.ValueEditField.Value = app.thresholdValue;
            
        end

        % Value changed function: ValueEditField
        function ValueEditFieldValueChanged(app, event)
           app.ValueEditField.Value = app.Slider.Value;
            
        end

        % Selection changed function: ClassifierButtonGroup
        function ClassifierButtonGroupSelectionChanged(app, event)
            selectedButton = app.ClassifierButtonGroup.SelectedObject;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.ThresholdingPanel = uipanel(app.UIFigure);
            app.ThresholdingPanel.Title = 'Thresholding';
            app.UIAxes_4 = uiaxes(app.ThresholdingPanel);
            app.UIAxes_4.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Slider = uislider(app.ThresholdingPanel);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 0.5;
            app.ValueEditFieldLabel = uilabel(app.ThresholdingPanel);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.ThresholdingPanel, 'numeric');
            app.ValueEditField.ValueChangedFcn = createCallbackFcn(app, @ValueEditFieldValueChanged, true);
            app.ExitButton = uibutton(app.UIFigure, 'state');
            app.ExitButton.ValueChangedFcn = createCallbackFcn(app, @ExitButtonValueChanged, true);
            app.ExitButton.Text = 'Exit';
            app.Panel = uipanel(app.UIFigure);
            app.SegmentationPropertiesLabel = uilabel(app.Panel);
            app.SegmentationPropertiesLabel.Text = 'Segmentation Properties';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.ExtractedFeaturesLabel = uilabel(app.Panel);
            app.ExtractedFeaturesLabel.Text = 'Extracted Features';
            app.UITable_2 = uitable(app.Panel);
            app.UITable_2.ColumnName = {};
            app.UITable_2.RowName = {};
            app.FiguresPanel = uipanel(app.UIFigure);
            app.FiguresPanel.Title = 'Figures';
            app.UIAxes = uiaxes(app.FiguresPanel);
            title(app.UIAxes, 'Raw Image');
            app.UIAxes_2 = uiaxes(app.FiguresPanel);
            title(app.UIAxes_2, 'Segmented BW Image');
            app.UIAxes_3 = uiaxes(app.FiguresPanel);
            title(app.UIAxes_3, 'Pseudo Colored Labels');
            app.ParameterSelectionPanel = uipanel(app.UIFigure);
            app.ParameterSelectionPanel.Title = 'Parameter Selection';
            app.Switch = uiswitch(app.ParameterSelectionPanel, 'toggle');
            app.Switch.Items = {'Test', 'Train'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Test';
            app.Lamp = uilamp(app.ParameterSelectionPanel);
            app.LoadButton = uibutton(app.ParameterSelectionPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SelectImageLabel = uilabel(app.ParameterSelectionPanel);
            app.SelectImageLabel.Text = 'Select Image';
            app.ListBox = uilistbox(app.ParameterSelectionPanel);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Enable = 'off';
            app.ListBox.Value = {};
            app.SegmentationButtonGroup = uibuttongroup(app.ParameterSelectionPanel);
            app.SegmentationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegmentationButtonGroupSelectionChanged, true);
            app.SegmentationButtonGroup.Title = 'Segmentation';
            app.SupervisedButton = uitogglebutton(app.SegmentationButtonGroup);
            app.SupervisedButton.Enable = 'off';
            app.SupervisedButton.Text = 'Supervised';
            app.SupervisedButton.Value = true;
            app.UnsupervisedButton = uitogglebutton(app.SegmentationButtonGroup);
            app.UnsupervisedButton.Enable = 'off';
            app.UnsupervisedButton.Text = 'Unsupervised';
            app.FeaturesButtonGroup = uibuttongroup(app.ParameterSelectionPanel);
            app.FeaturesButtonGroup.Title = 'Features';
            app.ColorButton = uiradiobutton(app.FeaturesButtonGroup);
            app.ColorButton.Text = 'Color';
            app.MorphologyButton = uiradiobutton(app.FeaturesButtonGroup);
            app.MorphologyButton.Text = 'Morphology';
            app.TextureButton = uiradiobutton(app.FeaturesButtonGroup);
            app.TextureButton.Text = 'Texture';
            app.AllButton = uiradiobutton(app.FeaturesButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.ClassifierButtonGroup = uibuttongroup(app.ParameterSelectionPanel);
            app.ClassifierButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClassifierButtonGroupSelectionChanged, true);
            app.ClassifierButtonGroup.Title = 'Classifier';
            app.LDAButton = uiradiobutton(app.ClassifierButtonGroup);
            app.LDAButton.Text = 'LDA';
            app.LDAButton.Value = true;
            app.SVMButton = uiradiobutton(app.ClassifierButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.KNNButton = uiradiobutton(app.ClassifierButtonGroup);
            app.KNNButton.Text = 'KNN';
            app.InputOuputPanel = uipanel(app.UIFigure);
            app.InputOuputPanel.Title = 'Input / Ouput';
            app.TextArea = uitextarea(app.InputOuputPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.InputParametersLabel = uilabel(app.InputOuputPanel);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.TextArea_2 = uitextarea(app.InputOuputPanel);
            app.OutputParametersLabel = uilabel(app.InputOuputPanel);
            app.OutputParametersLabel.Text = 'Output Parameters';
        end
    end

    methods (Access = public)

        % Construct app
        function app = myclassifier()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end